classdef InterfazECG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        ComenzarButton    matlab.ui.control.Button
        ArchivoButton     matlab.ui.control.StateButton
        LatidoLampLabel   matlab.ui.control.Label
        LatidoLamp        matlab.ui.control.Lamp
        RRIntervaloLabel  matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    
    properties (Access = public)
        senal_O; % Señal original
        fs; %Frecuencia de muestreo
        tO; %Tiempo señal original
        senal_I; % Señal Integrada
        tI; % Tiempo señal integrada
        senal_F; % Señal filtrada
        tF; % Tiempo señal filtrada
        Complejos_QRS; % Complejos QRS
        time_QRS; % Tiempo de cada QRS
        Complejos_PEAKI; % Posibles Complejos QRS
        time_PEAKI; % timpo de los posibles QRS
        file;
        path;
        beep;
        fs_beep;
        
    end
    
    
    methods (Access = private)
       
        
        function updategraphic_senalO(app)
            
            [app.beep, app.fs_beep]  = audioread("beep.wav");
            % Limpia las graficas 
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            % Datos de la señal original
            L = length(app.senal_O);
            T = L/app.fs;
            app.tO = linspace(0, T, L);
            %Necesarios
            parar = 0;
            listo = 0;
            i = 1; %Indices
            k = 1; %Indices
            u = 1; %Indices
            w = 1;
            d = 1;
            
            h = animatedline(app.UIAxes,'Color','y');
            h2 = animatedline(app.UIAxes_2,'Color','g');
            h3 = animatedline(app.UIAxes_3,'Color','b');
            axis(app.UIAxes,[min(app.tO),max(app.tO),min(app.senal_O),max(app.senal_O)])
            axis(app.UIAxes_2,[min(app.tF),max(app.tF),min(app.senal_F),max(app.senal_F)])
            axis(app.UIAxes_3,[min(app.tI),max(app.tI),min(app.senal_I),max(app.senal_I)])
            
            while(~parar)
                if i <= length(app.senal_O)
                   addpoints(h,app.tO(i),app.senal_O(i));
                   i = i+1;
                else
                   listo = listo + 1; 
                end
                if k <= length(app.senal_F)
                   addpoints(h2,app.tF(k),app.senal_F(k));
                   hold(app.UIAxes_2,'on')
                   if u <= length(app.Complejos_QRS)
                        if app.tF(k) == app.time_QRS(u)
                           plot(app.UIAxes_2,app.time_QRS(u),app.Complejos_QRS(u),'Marker','o','Color','r')
                           app.LatidoLamp.Color = [1 0 0];
                           app.LatidoLampLabel.Visible = 'on';
                           sound(app.beep,app.fs_beep);
                           pause(1)
                           if u == 1
                              we = num2str(app.time_QRS(u));
                              textI = '[0 - ';
                              textF = ']';
                              conc = strcat(textI,we,textF);
                              app.Label.Text = conc;
                           else
                              we = num2str(app.time_QRS(u-1));
                              we1 = num2str(app.time_QRS(u));
                              textI = '[';
                              gui = '-';
                              textF = ']';
                              conc = strcat(textI,we,gui,we1,textF);
                              app.Label.Text = conc;
                           end
                           app.Label.Enable = 'on';
                           pause(0.8)
                           u = u+1;
                        else
                           app.LatidoLamp.Color = [0 1 0];
                           app.LatidoLampLabel.Visible = 'off'; 
                        end
                   else
                      app.LatidoLamp.Color = [0 1 0];
                      app.LatidoLampLabel.Visible = 'off';  
                   end 
                   k = k+1;
                else
                    listo = listo +1;
                end
                if w <= length(app.senal_I)
                   addpoints(h3,app.tI(w),app.senal_I(w));
                   hold(app.UIAxes_3,'on')
                   if d <= length(app.Complejos_PEAKI)
                        if app.tI(w) == app.time_PEAKI(d)
                           plot(app.UIAxes_3,app.time_PEAKI(d),app.Complejos_PEAKI(d),'Marker','o','Color','r')
                           d = d+1;
                        end
                   end 
                   w = w+1;
                else
                    listo = listo +1;
                end
                
                if listo == 3
                    parar = 1;
                end
                
                listo = 0;
                drawnow limitrate
                
                
            end
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ArchivoButton
        function ArchivoButtonValueChanged(app, event)
            value = app.ArchivoButton.Value;
            [app.file, app.path] = uigetfile('*.mat');
            
            dirc = strcat(app.path,app.file);
            
            %% Tomar la señal ECG
            struct_1 = load(dirc,'-mat','val','Fs');
            app.senal_O = struct_1.val;
            app.fs = struct_1.Fs;
            
            
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)

            %% Comienza el algoritmo de pan-Tompkins
            [app.senal_I,app.senal_F] = pan_tompkins(app.fs,app.senal_O);
            %% Comienza la parte detectora de QRS
            [app.tI,app.tF,app.time_QRS,app.Complejos_QRS,app.Complejos_PEAKI,app.time_PEAKI]=detector_QRS2(app.senal_I,app.senal_F,app.fs);
            %% Graficas En tiempo real
            updategraphic_senalO(app);
            %updategraphic_senalF(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal Original ECG')
            xlabel(app.UIAxes, 'Tiempo [sec]')
            ylabel(app.UIAxes, 'Amplitud [mV]')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal Filtrada ECG')
            xlabel(app.UIAxes_2, 'Tiempo [sec]')
            ylabel(app.UIAxes_2, 'Amplitud [mV]')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Señal Integrada ECG')
            xlabel(app.UIAxes_3, 'Tiempo[sec]')
            ylabel(app.UIAxes_3, 'Amplitud [mV]')
            app.ComenzarButton = uibutton(app.UIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.ArchivoButton = uibutton(app.UIFigure, 'state');
            app.ArchivoButton.ValueChangedFcn = createCallbackFcn(app, @ArchivoButtonValueChanged, true);
            app.ArchivoButton.Text = 'Archivo';
            app.LatidoLampLabel = uilabel(app.UIFigure);
            app.LatidoLampLabel.Visible = 'off';
            app.LatidoLampLabel.Text = 'Latido';
            app.LatidoLamp = uilamp(app.UIFigure);
            app.RRIntervaloLabel = uilabel(app.UIFigure);
            app.RRIntervaloLabel.Text = 'RR-Intervalo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazECG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end